classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        ExTab                       matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        EyTab                       matlab.ui.container.Tab
        UIAxes4                     matlab.ui.control.UIAxes
        EzTab                       matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
        Tab                         matlab.ui.container.Tab
        UIAxes6                     matlab.ui.control.UIAxes
        Spatial2DTab                matlab.ui.container.Tab
        SpatialAxes                 matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        UpdateGeometryButtonPushed  matlab.ui.control.Button
        GeometryAxes                matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        MechanismSelectorTab        matlab.ui.container.Tab
        MechanismGroup              matlab.ui.container.ButtonGroup
        AllowedvariablesTextArea    matlab.ui.control.TextArea
        JzTextArea                  matlab.ui.control.TextArea
        JzTextAreaLabel             matlab.ui.control.Label
        JyTextArea                  matlab.ui.control.TextArea
        JyTextAreaLabel             matlab.ui.control.Label
        JxTextArea                  matlab.ui.control.TextArea
        JxTextAreaLabel             matlab.ui.control.Label
        CustomButton                matlab.ui.control.RadioButton
        PhotoDemberEffectButton     matlab.ui.control.RadioButton
        BuiltInFieldButton          matlab.ui.control.RadioButton
        NumericFieldsTab            matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        TemperatureKEditField       matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel  matlab.ui.control.Label
        AbsorptionDepthmEditField   matlab.ui.control.NumericEditField
        AbsorptionDepthLabel        matlab.ui.control.Label
        LifetimesEditField          matlab.ui.control.NumericEditField
        LifetimesEditFieldLabel     matlab.ui.control.Label
        MobilitymVsEditField        matlab.ui.control.NumericEditField
        MobilityLabel               matlab.ui.control.Label
        ExcitationTab               matlab.ui.container.Tab
        GridLayout3_2               matlab.ui.container.GridLayout
        PulseShapeDropDown          matlab.ui.control.DropDown
        PulseShapeDropDownLabel     matlab.ui.control.Label
        IncidenceAngledegEditField  matlab.ui.control.NumericEditField
        IncidenceAngledegEditFieldLabel  matlab.ui.control.Label
        SpotSizemEditField          matlab.ui.control.NumericEditField
        SpotSizemEditFieldLabel     matlab.ui.control.Label
        FluenceJcmEditField         matlab.ui.control.NumericEditField
        FluenceLabel                matlab.ui.control.Label
        PulseWidthFWHMsEditField    matlab.ui.control.NumericEditField
        PulseWidthLabel             matlab.ui.control.Label
        EtPositionTab               matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        ZmEditField                 matlab.ui.control.NumericEditField
        ZmEditFieldLabel            matlab.ui.control.Label
        YmEditField                 matlab.ui.control.NumericEditField
        YmEditFieldLabel            matlab.ui.control.Label
        XmEditField                 matlab.ui.control.NumericEditField
        XmEditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        SimResults % Struct containing results from simulateTHzFromDiffusion
    end
    
    methods (Access = private)
        
        function runDiffusionEngine(app)
            % Prepares parameters and executes standard drift-diffusion mechanism
            params = struct();
            
            % Simulation domain
            params.Lx = 1.5e-3; params.Ly = 1.5e-3; params.Lz = 3.0e-6;
            params.Nx = 601; params.Ny = 601; params.Nz = 201;
        
            % Time window and optical pulse
            params.Tmin = -5e-12; params.Tmax = 40e-12;
            params.tau_p = app.PulseWidthFWHMsEditField.Value / sqrt(2*log(2)); 
        
            % Basic material constants
            q = 1.602e-19; kB = 1.380649e-23;
            T = app.TemperatureKEditField.Value;
            
            % Validate inputs
            if app.LifetimesEditField.Value <= 0 || app.AbsorptionDepthmEditField.Value <= 0
                uialert(app.UIFigure, 'Lifetime and Absorption Depth must be positive.', 'Invalid Parameter');
                return;
            end
            
            % Einstein relations for mobilities and diffusion coefficients
            params.mu_e = app.MobilitymVsEditField.Value;
            params.mu_h = 0.1 * params.mu_e; 
            params.D_e = params.mu_e * kB * T / q; 
            params.D_h = params.mu_h * kB * T / q;
            params.D = params.D_e; 
            
            params.T = T;
            params.tau_r = app.LifetimesEditField.Value;
            params.alpha = 1 / max(app.AbsorptionDepthmEditField.Value, 1e-9);
            
            % Mechanism toggles
            params.usePhotoDember = app.PhotoDemberEffectButton.Value || app.CustomButton.Value;
            params.useBuiltIn = app.BuiltInFieldButton.Value || app.CustomButton.Value;
    
            % Depletion field properties
            params.Wdep = 0.5e-6; 
            params.Emax = 8e5;    
            params.Ebi_x = 5e4; params.Ebi_y = 0; % Transverse bias (if needed)
            
            % Beam geometry
            params.theta = deg2rad(app.IncidenceAngledegEditField.Value);
            params.w0 = app.SpotSizemEditField.Value / 2;
            params.w0x = params.w0 / max(cos(params.theta), 1e-6);
            params.w0y = params.w0;
        
            % Fluence to intensity conversion
            F_J_m2 = app.FluenceJcmEditField.Value * 1e4;
            params.I0 = (F_J_m2 * max(cos(params.theta), 0)) / max((params.tau_p * 4), eps);
        
            % Observation coordinates
            params.X0 = app.XmEditField.Value;
            params.Y0 = app.YmEditField.Value;
            params.z0 = app.ZmEditField.Value;
        
            % Execute physics engine
            app.SimResults = simulateTHzFromDiffusion(params);
        end
        
        function runCustomEngine(app)
            % Prepares parameters and executes custom user-defined mechanisms
            params = struct();
            
            % Simulation domain
            params.Lx = 1.5e-3; params.Ly = 1.5e-3; params.Lz = 3.0e-6;
            params.Nx = 601; params.Ny = 601; params.Nz = 201;
        
            % Time window and optical pulse
            params.Tmin = -5e-12; params.Tmax = 40e-12;
            params.tau_p = app.PulseWidthFWHMsEditField.Value / sqrt(2*log(2)); 
        
            % Basic material constants
            q = 1.602e-19; kB = 1.380649e-23;
            T = app.TemperatureKEditField.Value;
            
            % Validate inputs
            if app.LifetimesEditField.Value <= 0 || app.AbsorptionDepthmEditField.Value <= 0
                uialert(app.UIFigure, 'Lifetime and Absorption Depth must be positive.', 'Invalid Parameter');
                return;
            end
            
            % Material parameters
            q = 1.602e-19; kB = 1.380649e-23;
            params.T = app.TemperatureKEditField.Value;
            params.mu_e = app.MobilitymVsEditField.Value;
            params.mu = params.mu_e; 
            params.mu_h = 0.1 * params.mu_e;
            params.D_e = params.mu_e * kB * params.T / q;
            params.D_h = params.mu_h * kB * params.T / q;
            params.D = params.D_e;
            
            params.tau_r = app.LifetimesEditField.Value;
            params.alpha = 1 / max(app.AbsorptionDepthmEditField.Value, 1e-9);
            params.mechanism = 'Custom';
            
            % Parse user-defined mathematical strings
            fx = strjoin(cellstr(app.JxTextArea.Value), ' ');
            fy = strjoin(cellstr(app.JyTextArea.Value), ' ');
            fz = strjoin(cellstr(app.JzTextArea.Value), ' ');
            
            if isempty(strtrim(fx)), fx = '0'; end
            if isempty(strtrim(fy)), fy = '0'; end
            if isempty(strtrim(fz)), fz = '0'; end
            
            try
                % Compile formulas to function handles
                hx = str2func(['@(n,dnx,dny,X,Y,t) ' fx]);
                hy = str2func(['@(n,dnx,dny,X,Y,t) ' fy]);
                hz = str2func(['@(n,dnx,dny,X,Y,t) ' fz]);
            
                params.CustomJxHandle = @(n,dnx,dny,X,Y,t) deal(hx(n,dnx,dny,X,Y,t), hy(n,dnx,dny,X,Y,t), hz(n,dnx,dny,X,Y,t));
            
                % Safety Check: Ensure outputs match spatial dimensions
                n0 = ones(3,4); d0 = zeros(3,4); t0 = 0;
                [Jxt, Jyt, Jzt] = params.CustomJxHandle(n0, d0, d0, d0, d0, t0);
                
                if ~isequal(size(Jxt), size(n0)) || ~isequal(size(Jyt), size(n0)) || ~isequal(size(Jzt), size(n0))
                    error('Jx/Jy/Jz must return matrices matching the size of n (Ny x Nx).');
                end
            catch ME
                uialert(app.UIFigure, ['Formula syntax or dimension error: ' ME.message], 'Formula Error');
                return;
            end
        
            % Beam and observation geometry
            params.theta = deg2rad(app.IncidenceAngledegEditField.Value);
            params.w0 = app.SpotSizemEditField.Value / 2;
            params.w0x = params.w0 / max(cos(params.theta), 1e-6);
            params.w0y = params.w0;
            
            F_J_m2 = app.FluenceJcmEditField.Value * 1e4;
            params.I0 = (F_J_m2 * max(cos(params.theta), 0)) / max((params.tau_p * 4), eps);
            
            params.X0 = app.XmEditField.Value;
            params.Y0 = app.YmEditField.Value;
            params.z0 = app.ZmEditField.Value;
        
            try
                app.SimResults = simulateTHzFromDiffusion(params);
            catch ME
                uialert(app.UIFigure, ME.message, 'Simulation Engine Failed');
            end
        end
        
        function plotTransientCurrentGUI(app)
            if isempty(app.SimResults), return; end
            
            scale = 1e22;
            plot(app.UIAxes, app.SimResults.t_ps, app.SimResults.n_center_time / scale);
            xlabel(app.UIAxes, 't (ps)');
            ylabel(app.UIAxes, sprintf('n(0,0,t) / %.0e [m^{-3}]', scale));
            title(app.UIAxes, 'Central carrier density (proxy for transient current)');
            grid(app.UIAxes, 'on');
        end
        
        function plotCarrierDistributionGUI(app)
            if isempty(app.SimResults) || isempty(app.SimResults.n_at_peak_center), return; end
            
            imagesc(app.UIAxes2, app.SimResults.x * 1e6, app.SimResults.y * 1e6, app.SimResults.n_at_peak_center);
            axis(app.UIAxes2, 'image');
            set(app.UIAxes2, 'YDir', 'normal');
            xlabel(app.UIAxes2, 'x [\mum]');
            ylabel(app.UIAxes2, 'y [\mum]');
            title(app.UIAxes2, sprintf('n(x,y) at t = %.3f ps (centre peak)', app.SimResults.peak_center_time_ps));
            colorbar(app.UIAxes2);
        end
        
        function plotTHzFieldGUI(app)
            if isempty(app.SimResults), return; end
            
            t = app.SimResults.t_ps;
            
            plot(app.UIAxes3, t, app.SimResults.Ex_pt, 'r', 'LineWidth', 1.5);
            xlabel(app.UIAxes3, 't (ps)'); 
            ylabel(app.UIAxes3, 'Ex [V/m]');
            title(app.UIAxes3, 'X Component'); 
            grid(app.UIAxes3, 'on');
            
            plot(app.UIAxes4, t, app.SimResults.Ey_pt, 'g', 'LineWidth', 1.5);
            xlabel(app.UIAxes4, 't (ps)'); 
            ylabel(app.UIAxes4, 'Ey [V/m]');
            title(app.UIAxes4, 'Y Component'); 
            grid(app.UIAxes4, 'on');
            
            plot(app.UIAxes5, t, app.SimResults.Ez_pt, 'b', 'LineWidth', 1.5);
            xlabel(app.UIAxes5, 't (ps)'); 
            ylabel(app.UIAxes5, 'Ez [V/m]');
            title(app.UIAxes5, 'Z Component'); 
            grid(app.UIAxes5, 'on');
        
            Ex = app.SimResults.Ex_pt;
            Ey = app.SimResults.Ey_pt;
            Ez = app.SimResults.Ez_pt;
            
            X0 = app.XmEditField.Value;
            Y0 = app.YmEditField.Value;
            Z0 = app.ZmEditField.Value;
            
            R = sqrt(X0^2 + Y0^2 + Z0^2);
            
            if R == 0
                E_det = zeros(size(Ex));
            else
                ux = Z0 / R;
                uz = -X0 / R;
                E_det = Ex * ux + Ez * uz;
            end
            
            cla(app.UIAxes6);
            
            plot(app.UIAxes6, t, Ex, 'r', 'LineWidth', 1.2);
            hold(app.UIAxes6, 'on');
            
            plot(app.UIAxes6, t, Ez, 'b', 'LineWidth', 1.2);
            
            plot(app.UIAxes6, t, E_det, 'k', 'LineWidth', 1.5);
            
            hold(app.UIAxes6, 'off');
            
            xlabel(app.UIAxes6, 't (ps)');
            ylabel(app.UIAxes6, 'E [V/m]');
            title(app.UIAxes6, 'Field Component Comparison');
            legend(app.UIAxes6, {'Fixed E_x', 'Fixed E_z', 'Rotating E_{det}'}, ...
                   'Location', 'best');
            grid(app.UIAxes6, 'on');
        
            Epp_Ex = (max(Ex) - min(Ex)) * R;
            Epp_Ez = (max(Ez) - min(Ez)) * R;
            Epp_Edet = (max(E_det) - min(E_det)) * R;
        
            fprintf('\n--- Current observation point ---\n');
            fprintf('X0 = %.4f mm, Y0 = %.4f mm, Z0 = %.4f mm\n', X0*1e3, Y0*1e3, Z0*1e3);
            fprintf('R  = %.4f mm\n', R*1e3);
            fprintf('Epp_Ex   * R = %.4f\n', Epp_Ex);
            fprintf('Epp_Ez   * R = %.4f\n', Epp_Ez);
            fprintf('Epp_Edet * R = %.4f\n', Epp_Edet);
        end
        
        function plotSample(app)
            hold(app.GeometryAxes, 'on');
            
            Lx = 2e-3; Ly = 2e-3; Lz = 0.5e-3;
            x = [-Lx/2, Lx/2]; y = [-Ly/2, Ly/2]; z = [0, Lz];
            [X, Y, Z] = meshgrid(x, y, z);
    
            faces = {
                [X(1,1,1) Y(1,1,1) Z(1,1,1); X(2,1,1) Y(2,1,1) Z(2,1,1); X(2,2,1) Y(2,2,1) Z(2,2,1); X(1,2,1) Y(1,2,1) Z(1,2,1)], ...
                [X(1,1,2) Y(1,1,2) Z(1,1,2); X(2,1,2) Y(2,1,2) Z(2,1,2); X(2,2,2) Y(2,2,2) Z(2,2,2); X(1,2,2) Y(1,2,2) Z(1,2,2)], ...
                [X(1,1,1) Y(1,1,1) Z(1,1,1); X(1,2,1) Y(1,2,1) Z(1,2,1); X(1,2,2) Y(1,2,2) Z(1,2,2); X(1,1,2) Y(1,1,2) Z(1,1,2)], ...
                [X(2,1,1) Y(2,1,1) Z(2,1,1); X(2,2,1) Y(2,2,1) Z(2,2,1); X(2,2,2) Y(2,2,2) Z(2,2,2); X(2,1,2) Y(2,1,2) Z(2,1,2)], ...
                [X(1,2,1) Y(1,2,1) Z(1,2,1); X(2,2,1) Y(2,2,1) Z(2,2,1); X(2,2,2) Y(2,2,2) Z(2,2,2); X(1,2,2) Y(1,2,2) Z(1,2,2)], ...
                [X(1,1,1) Y(1,1,1) Z(1,1,1); X(2,1,1) Y(2,1,1) Z(2,1,1); X(2,1,2) Y(2,1,2) Z(2,1,2); X(1,1,2) Y(1,1,2) Z(1,1,2)]
            };
    
            for i = 1:length(faces)
                f = faces{i};
                patch(app.GeometryAxes, f(:,1), f(:,2), f(:,3), [0.5 0.7 1], 'FaceAlpha', 0.5, 'EdgeColor', 'k');
            end
        end
        
        function plotBeam(app)
            hold(app.GeometryAxes, 'on');
            
            beamRadius = app.SpotSizemEditField.Value / 2;
            zStart = -2e-3; zEnd = 2e-3;
    
            [Xc, Yc, Zc] = cylinder(beamRadius, 80);
            Zc = Zc * (zEnd - zStart) + zStart;
            
            theta = deg2rad(app.IncidenceAngledegEditField.Value);
            Xr = Xc * cos(theta) + Zc * sin(theta);
            Zr = -Xc * sin(theta) + Zc * cos(theta);
            
            surf(app.GeometryAxes, Xr, Yc, Zr, 'FaceColor', [1, 0.5, 0], 'FaceAlpha', 0.35, 'EdgeColor', 'none');
            title(app.GeometryAxes, 'Laser Beam Visualization');
            hold(app.GeometryAxes, 'off');
        end

        function plotSpatialRadiationGUI(app)
            if isempty(app.SimResults) || ~isfield(app.SimResults, 'Ex_2D')
                return; 
            end
            
            % Clear previous plot
            cla(app.SpatialAxes);
            
            % Convert spatial coordinates to micrometers for plotting
            X_um = app.SimResults.X0_grid * 1e6;
            Y_um = app.SimResults.Y0_grid * 1e6;
            
            % Plot vector field using quiver
            quiver(app.SpatialAxes, X_um, Y_um, app.SimResults.Ex_2D, app.SimResults.Ey_2D, 1.5, 'c');
            
            % Formatting to match Figure 3 in the Interim Report
            axis(app.SpatialAxes, 'equal');
            xlabel(app.SpatialAxes, 'x [\mum]');
            ylabel(app.SpatialAxes, 'y [\mum]');
            title(app.SpatialAxes, sprintf('Ex, Ey field vectors at t = %.2f ps, z = %.1f mm', ...
                app.SimResults.t_obs_peak * 1e12, app.SimResults.z0 * 1e3));
            grid(app.SpatialAxes, 'on');
        end
        
        function updateGeometry(app)
            cla(app.GeometryAxes);
            plotSample(app);
            plotBeam(app);
            
            axis(app.GeometryAxes, 'equal');
            view(app.GeometryAxes, 3, 2);
            grid(app.GeometryAxes, 'on');
        end   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.GeometryAxes, 'equal');
            view(app.GeometryAxes, 3, 2);
            grid(app.GeometryAxes, 'on');
            xlabel(app.GeometryAxes, 'x'); ylabel(app.GeometryAxes, 'y'); zlabel(app.GeometryAxes, 'z');
            app.MechanismGroup.BorderType = 'none';
        end

        % Button pushed function: UpdateGeometryButtonPushed
        function UpdateGeometryButtonPushedPushed(app, event)
            app.SimResults = []; % Clear old results to prevent plotting stale data
            
            updateGeometry(app);
        
            try
                if app.CustomButton.Value
                    runCustomEngine(app);
                else
                    runDiffusionEngine(app);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Simulation Failed');
                return;
            end
        
            plotTransientCurrentGUI(app);
            plotCarrierDistributionGUI(app);
            plotTHzFieldGUI(app);
            plotSpatialRadiationGUI(app);
        
            axis(app.GeometryAxes, 'equal');
            grid(app.GeometryAxes, 'on');
            view(app.GeometryAxes, 3);
            xlim(app.GeometryAxes, [-3e-3 3e-3]);
            ylim(app.GeometryAxes, [-3e-3 3e-3]);
            zlim(app.GeometryAxes, [-3e-3 3e-3]);
            
        end

        % Callback function: AllowedvariablesTextArea, JxTextArea, 
        % ...and 3 other components
        function MechanismGroupSelectionChanged(app, event)
            if app.MechanismGroup.SelectedObject == app.CustomButton
                visibility = 'on';
            else
                visibility = 'off';
            end
            
            app.JxTextArea.Visible = visibility;
            app.JxTextAreaLabel.Visible = visibility;
            app.JyTextArea.Visible = visibility;
            app.JyTextAreaLabel.Visible = visibility;
            app.JzTextArea.Visible = visibility;
            app.JzTextAreaLabel.Visible = visibility;
            app.AllowedvariablesTextArea.Visible = visibility;
        end

        % Callback function
        function UpdateGeometryButtonPushedPushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.MechanismSelectorTab = uitab(app.TabGroup);
            app.MechanismSelectorTab.Title = 'Mechanism Selector';
            app.MechanismGroup = uibuttongroup(app.MechanismSelectorTab);
            app.MechanismGroup.SelectionChangedFcn = createCallbackFcn(app, @MechanismGroupSelectionChanged, true);
            app.BuiltInFieldButton = uiradiobutton(app.MechanismGroup);
            app.BuiltInFieldButton.Text = 'Built-In Field';
            app.BuiltInFieldButton.Value = true;
            app.PhotoDemberEffectButton = uiradiobutton(app.MechanismGroup);
            app.PhotoDemberEffectButton.Text = 'Photo-Dember Effect';
            app.CustomButton = uiradiobutton(app.MechanismGroup);
            app.CustomButton.Text = 'Custom';
            app.JxTextAreaLabel = uilabel(app.MechanismGroup);
            app.JxTextAreaLabel.Visible = 'off';
            app.JxTextAreaLabel.Text = 'Jx ';
            app.JxTextArea = uitextarea(app.MechanismGroup);
            app.JxTextArea.ValueChangingFcn = createCallbackFcn(app, @MechanismGroupSelectionChanged, true);
            app.JxTextArea.Visible = 'off';
            app.JyTextAreaLabel = uilabel(app.MechanismGroup);
            app.JyTextAreaLabel.Visible = 'off';
            app.JyTextAreaLabel.Text = 'Jy ';
            app.JyTextArea = uitextarea(app.MechanismGroup);
            app.JyTextArea.ValueChangingFcn = createCallbackFcn(app, @MechanismGroupSelectionChanged, true);
            app.JyTextArea.Visible = 'off';
            app.JzTextAreaLabel = uilabel(app.MechanismGroup);
            app.JzTextAreaLabel.Visible = 'off';
            app.JzTextAreaLabel.Text = 'Jz ';
            app.JzTextArea = uitextarea(app.MechanismGroup);
            app.JzTextArea.ValueChangingFcn = createCallbackFcn(app, @MechanismGroupSelectionChanged, true);
            app.JzTextArea.Visible = 'off';
            app.AllowedvariablesTextArea = uitextarea(app.MechanismGroup);
            app.AllowedvariablesTextArea.ValueChangingFcn = createCallbackFcn(app, @MechanismGroupSelectionChanged, true);
            app.AllowedvariablesTextArea.Tag = 'Allowed variables';
            app.AllowedvariablesTextArea.Editable = 'off';
            app.AllowedvariablesTextArea.Visible = 'off';
            app.AllowedvariablesTextArea.Value = {'Allowed variables'; 'n     : carrier density n(x,y,t) [m^-3]'; 'dnx   : ∂n/∂x [m^-4]'; 'dny   : ∂n/∂y [m^-4]'; 'X     : x-coordinate grid [m]'; 'Y     : y-coordinate grid [m]'; 't     : time [s]'};
            app.NumericFieldsTab = uitab(app.TabGroup);
            app.NumericFieldsTab.Title = 'Numeric Fields';
            app.GridLayout3 = uigridlayout(app.NumericFieldsTab);
            app.MobilityLabel = uilabel(app.GridLayout3);
            app.MobilityLabel.Text = {'Mobility'; '(m²/(V·s)'};
            app.MobilitymVsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MobilitymVsEditField.Value = 0.8;
            app.LifetimesEditFieldLabel = uilabel(app.GridLayout3);
            app.LifetimesEditFieldLabel.Text = {'Lifetime'; '(s)'};
            app.LifetimesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LifetimesEditField.Value = 1e-10;
            app.AbsorptionDepthLabel = uilabel(app.GridLayout3);
            app.AbsorptionDepthLabel.Text = {'Absorption'; 'Depth (m)'};
            app.AbsorptionDepthmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AbsorptionDepthmEditField.Value = 1.4e-07;
            app.TemperatureKEditFieldLabel = uilabel(app.GridLayout3);
            app.TemperatureKEditFieldLabel.Text = {'Temperature'; '(K)'};
            app.TemperatureKEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TemperatureKEditField.Value = 300;
            app.ExcitationTab = uitab(app.TabGroup);
            app.ExcitationTab.Title = 'Excitation';
            app.GridLayout3_2 = uigridlayout(app.ExcitationTab);
            app.PulseWidthLabel = uilabel(app.GridLayout3_2);
            app.PulseWidthLabel.Text = {'Pulse Width'; '(FWHM, s)'};
            app.PulseWidthFWHMsEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.PulseWidthFWHMsEditField.Value = 1e-13;
            app.FluenceLabel = uilabel(app.GridLayout3_2);
            app.FluenceLabel.Text = {'Fluence '; '(J/cm²)'};
            app.FluenceJcmEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.FluenceJcmEditField.Value = 0.0001;
            app.SpotSizemEditFieldLabel = uilabel(app.GridLayout3_2);
            app.SpotSizemEditFieldLabel.Text = {'Spot Size'; '(m)'};
            app.SpotSizemEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.SpotSizemEditField.Value = 0.000424;
            app.IncidenceAngledegEditFieldLabel = uilabel(app.GridLayout3_2);
            app.IncidenceAngledegEditFieldLabel.Text = {'Incidence'; 'Angle (deg)'};
            app.IncidenceAngledegEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.PulseShapeDropDownLabel = uilabel(app.GridLayout3_2);
            app.PulseShapeDropDownLabel.Text = {'Pulse '; 'Shape'};
            app.PulseShapeDropDown = uidropdown(app.GridLayout3_2);
            app.PulseShapeDropDown.Items = {'Gaussian', 'Self-defined'};
            app.PulseShapeDropDown.Value = 'Gaussian';
            app.EtPositionTab = uitab(app.TabGroup);
            app.EtPositionTab.Title = 'E(t) Position';
            app.GridLayout7 = uigridlayout(app.EtPositionTab);
            app.XmEditFieldLabel = uilabel(app.GridLayout7);
            app.XmEditFieldLabel.Text = {'X'; '(m)'};
            app.XmEditField = uieditfield(app.GridLayout7, 'numeric');
            app.XmEditField.Value = 0.0032;
            app.YmEditFieldLabel = uilabel(app.GridLayout7);
            app.YmEditFieldLabel.Text = {'Y'; '(m)'};
            app.YmEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.GridLayout7);
            app.ZmEditFieldLabel.Text = {'Z'; '(m)'};
            app.ZmEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ZmEditField.Value = 0.00773;
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.GeometryAxes = uiaxes(app.GridLayout5);
            title(app.GeometryAxes, 'Semiconductor & Laser Beam')
            xlabel(app.GeometryAxes, 'X')
            ylabel(app.GeometryAxes, 'Y')
            zlabel(app.GeometryAxes, 'Z')
            app.UpdateGeometryButtonPushed = uibutton(app.GridLayout5, 'push');
            app.UpdateGeometryButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @UpdateGeometryButtonPushedPushed, true);
            app.UpdateGeometryButtonPushed.Text = 'Update Geometry';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Central Carrier Density')
            xlabel(app.UIAxes, 't (ps)')
            ylabel(app.UIAxes, 'n (arb)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2, 'Carrier Distribution')
            xlabel(app.UIAxes2, 'x (\mum)')
            ylabel(app.UIAxes2, 'y (\mum)')
            zlabel(app.UIAxes2, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.ExTab = uitab(app.TabGroup2);
            app.ExTab.Title = 'Ex';
            app.UIAxes3 = uiaxes(app.ExTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.EyTab = uitab(app.TabGroup2);
            app.EyTab.Title = 'Ey';
            app.UIAxes4 = uiaxes(app.EyTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.EzTab = uitab(app.TabGroup2);
            app.EzTab.Title = 'Ez';
            app.UIAxes5 = uiaxes(app.EzTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UIAxes6 = uiaxes(app.Tab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Spatial2DTab = uitab(app.TabGroup2);
            app.Spatial2DTab.Title = 'Spatial 2D';
            app.SpatialAxes = uiaxes(app.Spatial2DTab);
            title(app.SpatialAxes, 'Title')
            xlabel(app.SpatialAxes, 'X')
            ylabel(app.SpatialAxes, 'Y')
            zlabel(app.SpatialAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end